classdef QuITO_graphicalInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QuITOGraphicalInterfaceUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        GridLayout3                     matlab.ui.container.GridLayout
        MeshingStrategyDropDown         matlab.ui.control.DropDown
        MeshingStrategyDropDownLabel    matlab.ui.control.Label
        GeneratingFunctionDropDown      matlab.ui.control.DropDown
        GeneratingFunctionDropDownLabel  matlab.ui.control.Label
        ShapeParameterDEditField        matlab.ui.control.NumericEditField
        ShapeParameterDEditFieldLabel   matlab.ui.control.Label
        NumberofStepsNEditField         matlab.ui.control.NumericEditField
        NumberofStepsNEditFieldLabel    matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        SelectExampleDropDown           matlab.ui.control.DropDown
        SelectExampleLabel              matlab.ui.control.Label
        QuITOv2GraphicalInterfaceLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% Setting up and Importing Dependencies
            clc;      
            close all;
            
            % Set Ready status
            app.ReadyLampLabel.Text = 'Busy';
            app.ReadyLamp.Color = '#ff0000';
            drawnow;
            
            % Add the solver function to the path
            addpath('./src/')
            
            % Get user input values
            N = app.NumberofStepsNEditField.Value;
            D = app.ShapeParameterDEditField.Value;
            genFuncFlag = str2double(app.GeneratingFunctionDropDown.Value);                  
            mesh_strategy= str2double(app.MeshingStrategyDropDown.Value);
            
            % Adding paths to the file
            try
                if strcmp(app.SelectExampleDropDown.Value, 'Aly Chan')
                    addpath('./examples/Aly Chan')
                    problem = AlyChan;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Bang Bang problem')
                    addpath('./examples/Bang Bang problem')
                    problem = bang_bang;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Bressan problem')
                    addpath('./examples/Bressan problem')
                    problem = bressan;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Catalyst mixing problem')
                    addpath('./examples/Catalyst mixing problem')
                    problem = catalyst;
                elseif strcmp(app.SelectExampleDropDown.Value, 'SIRI problem')
                    addpath('./examples/SIRI problem')
                    problem = siri;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Bryson Denham')
                    addpath('./examples/Bryson Denham')
                    problem = BrysonDenham;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Double integrator tracking')
                    addpath('./examples/Double integrator tracking')
                    problem = DoubleIntTrack;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Inverted pendulum on a cart')
                    addpath('./examples/Inverted pendulum on a cart')
                    problem = invertedPendulumOnCart;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Multi-agent AUV path planning')
                    addpath('./examples/Multi-agent AUV path planning')
                    problem = AUVpathplan;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with control constraints)')
                    addpath('./examples/Rayleigh problem (with control constraints)')
                    problem = RayleighProblemControlConstraints;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
                    addpath('./examples/Rayleigh problem (with mixed constraints)')
                    problem = RayleighProblemMixedConstraint;
                elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
                    addpath('./examples/Robot Path Planning')   
                    problem = RobotPathPlanning;
                elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol control constrained')
                    addpath('./examples/VanDerPol control constrained')
                    problem = VanderPolControlConstraints;
                elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol state constrained')
                    addpath('./examples/VanDerPol state constrained')
                    problem = VanDerPol;
                elseif strcmp(app.SelectExampleDropDown.Value, 'User-defined template problem')
                    addpath('./TemplateProblem/')
                    problem = TemplateProblem;
                end
            catch
                app.ReadyLampLabel.Text = 'Ready';
                app.ReadyLamp.Color = '#00ff00';
                drawnow;

                error(['Present Working Directory is not the QuITO base directory. ' ...
                    'Please navigate to the QuITO base directory before proceeding.'])
            end

            %% Running the solve problem function for the selected problem   
            % Options for solving the problem
            opts = options(N,D,genFuncFlag);
            if mesh_strategy==1                
                opts.meshstrategy='fixed';
            else
                opts.meshstrategy='mesh refinement';
            end
            
            
            % Solving the problem
%             if strcmp(app.SelectExampleDropDown.Value, 'AUV path planning')
%                 solution = solveInvertedPendulumOnCart(problem, opts);
%             elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
%                 solution = solveRayleighProblemMixedConstraint(problem, opts);
%             elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
%                 solution = solveRobotPathPlanning(problem, opts);
%             else
%                 solution = solveProblem(problem, opts);
%             end
            solution=solveProblem(problem, opts);
            
            % Post Processing and plotting
            postProcess(solution, problem, opts);

            %% Remove the earlier added paths to avoid conflicts
            if strcmp(app.SelectExampleDropDown.Value, 'Aly Chan')
                rmpath('./examples/Aly Chan')               
             elseif strcmp(app.SelectExampleDropDown.Value, 'AUV path planning')
                rmpath('./examples/AUV path planning')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Bang Bang problem')
                rmpath('./examples/Bang Bang problem')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Bressan problem')
                rmpath('./examples/Bressan problem')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Catalyst mixing problem')
                rmpath('./examples/Catalyst mixing problem')
            elseif strcmp(app.SelectExampleDropDown.Value, 'SIRI problem')
                rmpath('./examples/SIRI problem')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Bryson Denham')
                rmpath('./examples/Bryson Denham')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Double integrator tracking')
                rmpath('./examples/Double integrator tracking')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Inverted pendulum on a cart')
                rmpath('./examples/Inverted pendulum on a cart')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with control constraints)')
                rmpath('./examples/Rayleigh problem (with control constraints)')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Rayleigh problem (with mixed constraints)')
                rmpath('./examples/Rayleigh problem (with mixed constraints)')
            elseif strcmp(app.SelectExampleDropDown.Value, 'Robot Path Planning')
                rmpath('./examples/Robot Path Planning')   
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol control constrained')
                rmpath('./examples/VanDerPol control constrained')
            elseif strcmp(app.SelectExampleDropDown.Value, 'VanDerPol state constrained')
                rmpath('./examples/VanDerPol state constrained')
            elseif strcmp(app.SelectExampleDropDown.Value, 'User-defined template problem')
                rmpath('./TemplateProblem/')
            end

            %% Updating the UI once the process is complete
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp.Color = '#00ff00';
            drawnow;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QuITOGraphicalInterfaceUIFigure = uifigure('Visible', 'off');
            app.QuITOGraphicalInterfaceUIFigure.Name = 'QuITO - Graphical Interface';
            app.GridLayout = uigridlayout(app.QuITOGraphicalInterfaceUIFigure);
            app.QuITOv2GraphicalInterfaceLabel = uilabel(app.GridLayout);
            app.QuITOv2GraphicalInterfaceLabel.Text = 'QuITO v.2 : Graphical Interface';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectExampleLabel = uilabel(app.GridLayout2);
            app.SelectExampleLabel.Text = 'Select Example:';
            app.SelectExampleDropDown = uidropdown(app.GridLayout2);
            app.SelectExampleDropDown.Items = {'Aly Chan', 'Bang Bang problem', 'Bressan problem', 'Bryson Denham', 'Catalyst mixing problem', 'Double integrator tracking', 'Inverted pendulum on a cart', 'Multi-agent AUV path planning', 'Rayleigh problem (with control constraints)', 'Rayleigh problem (with mixed constraints)', 'Robot Path Planning', 'SIRI problem', 'VanDerPol control constrained', 'VanDerPol state constrained', 'User-defined template problem'};
            app.SelectExampleDropDown.Value = 'Aly Chan';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NumberofStepsNEditFieldLabel = uilabel(app.GridLayout3);
            app.NumberofStepsNEditFieldLabel.Text = 'Number of Steps (N)';
            app.NumberofStepsNEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NumberofStepsNEditField.Limits = [2 Inf];
            app.NumberofStepsNEditField.RoundFractionalValues = 'on';
            app.NumberofStepsNEditField.ValueDisplayFormat = '%.0f';
            app.NumberofStepsNEditField.Value = 100;
            app.ShapeParameterDEditFieldLabel = uilabel(app.GridLayout3);
            app.ShapeParameterDEditFieldLabel.Text = 'Shape Parameter (D)';
            app.ShapeParameterDEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ShapeParameterDEditField.Limits = [0 Inf];
            app.ShapeParameterDEditField.Value = 2;
            app.GeneratingFunctionDropDownLabel = uilabel(app.GridLayout3);
            app.GeneratingFunctionDropDownLabel.Text = 'Generating Function';
            app.GeneratingFunctionDropDown = uidropdown(app.GridLayout3);
            app.GeneratingFunctionDropDown.Items = {'Laguerre gaussian order 2', 'Laguerre gaussian order 4', 'Laguerre gaussian order 6', 'Hermite polynomial order 10', 'Trigonometric guassian order 4', 'Hyperbolic secant order 2'};
            app.GeneratingFunctionDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.GeneratingFunctionDropDown.Value = '1';
            app.MeshingStrategyDropDownLabel = uilabel(app.GridLayout3);
            app.MeshingStrategyDropDownLabel.Text = 'Meshing Strategy';
            app.MeshingStrategyDropDown = uidropdown(app.GridLayout3);
            app.MeshingStrategyDropDown.Items = {'Fixed Mesh', 'Local Mesh Refinement'};
            app.MeshingStrategyDropDown.ItemsData = {'1', '2'};
            app.MeshingStrategyDropDown.Value = '1';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.RunButton = uibutton(app.GridLayout4, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = '208-2087614_green-transparent-play-button-hd-png-download.png';
            app.RunButton.Text = 'Run';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.ReadyLampLabel = uilabel(app.GridLayout5);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.GridLayout5);
            % Show the figure after all components are created
            app.QuITOGraphicalInterfaceUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QuITO_graphicalInterface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QuITOGraphicalInterfaceUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QuITOGraphicalInterfaceUIFigure)
        end
    end
end